classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComputerVisionChallenge18      matlab.ui.Figure
        BlickwinkelLabel               matlab.ui.control.Label
        AngabeBlickwinkel              matlab.ui.control.NumericEditField
        Ueberschrift                   matlab.ui.control.Label
        Gruppe11Label                  matlab.ui.control.Label
        Beschreibung                   matlab.ui.control.Label
        Button3Ansichtberechnen        matlab.ui.control.Button
        LinkeAnsichtEditFieldLabel     matlab.ui.control.Label
        AngabeLinkeAnsicht             matlab.ui.control.EditField
        RechteAnsichtEditFieldLabel    matlab.ui.control.Label
        AngabeRechteAnsicht            matlab.ui.control.EditField
        ButtonLinkeAnsichtladen        matlab.ui.control.Button
        ButtonRechteAnsichtLaden       matlab.ui.control.Button
        BerechnungszeitEditFieldLabel  matlab.ui.control.Label
        AngabeBerechnungszeit          matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        minLabel                       matlab.ui.control.Label
        UIAxesLinkeAnsicht             matlab.ui.control.UIAxes
        UIAxesBerechneteAnsicht        matlab.ui.control.UIAxes
        UIAxesRechteAnsicht            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        p = 0.5; % Blickwinkel
        img1 % Linke Ansicht
        img2 % Rechte Ansicht
        elapsed_time = '0' % Berechnungszeit
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxesLinkeAnsicht.XTick = [];
            app.UIAxesLinkeAnsicht.YTick = [];
            app.UIAxesLinkeAnsicht.Visible = 'off';
            app.UIAxesBerechneteAnsicht.XTick = [];
            app.UIAxesBerechneteAnsicht.YTick = [];
            app.UIAxesBerechneteAnsicht.Visible = 'off';
            app.UIAxesRechteAnsicht.XTick = [];
            app.UIAxesRechteAnsicht.YTick = [];
            app.UIAxesRechteAnsicht.Visible = 'off';
            app.AngabeBerechnungszeit.Value = app.elapsed_time;
            app.AngabeBlickwinkel.Value = app.p;
        end

        % Button pushed function: ButtonLinkeAnsichtladen
        function ButtonLinkeAnsichtladenPushed(app, event)
            [leftImg,leftPath] = uigetfile( ...
            {'*.png;*.jpg;*.JPG;*.bmp','Pictures (*.png,*.jpg,*.bmp)'},...
            'Select a File');
            app.ComputerVisionChallenge18.Visible = 'off';
            app.ComputerVisionChallenge18.Visible = 'on';
            app.img1 = imread([leftPath leftImg]);
            app.AngabeLinkeAnsicht.Value = [leftPath leftImg];
            % IGray1 = rgb_to_gray(app.img1);
            imshow(app.img1, 'Parent', app.UIAxesLinkeAnsicht,...
                'XData', [0 app.UIAxesRechteAnsicht.Position(3)],...
                'YData', [0 app.UIAxesRechteAnsicht.Position(4)]);
        end

        % Button pushed function: ButtonRechteAnsichtLaden
        function ButtonRechteAnsichtLadenPushed(app, event)
            [rightImg,rightPath] = uigetfile( ...
            {'*.png;*.jpg;*.JPG;*.bmp','Pictures (*.png,*.jpg,*.bmp)'},...
            'Select a File');
            app.ComputerVisionChallenge18.Visible = 'off';
            app.ComputerVisionChallenge18.Visible = 'on';
            app.img2 = imread([rightPath rightImg]);
            app.AngabeRechteAnsicht.Value = [rightPath rightImg];
            % IGray2 = rgb_to_gray(app.img2);
            imshow(app.img2, 'Parent', app.UIAxesRechteAnsicht,...
                'XData', [0 app.UIAxesRechteAnsicht.Position(3)],...
                'YData', [0 app.UIAxesRechteAnsicht.Position(4)]);
        end

        % Value changed function: AngabeBlickwinkel
        function AngabeBlickwinkelValueChanged(app, event)
            app.p = app.AngabeBlickwinkel.Value;
        end

        % Button pushed function: Button3Ansichtberechnen
        function Button3AnsichtberechnenPushed(app, event)
            tic
            img_viewpoint = free_viewpoint(app.img1, app.img2, app.p);
            app.elapsed_time = toc;
            imshow(img_viewpoint, 'Parent', app.UIAxesBerechneteAnsicht,...
                'XData', [0 app.UIAxesBerechneteAnsicht.Position(3)],...
                'YData', [0 app.UIAxesBerechneteAnsicht.Position(4)]);
            app.elapsed_time = app.elapsed_time/60;
            app.elapsed_time = round(app.elapsed_time*100)/100;
            app.AngabeBerechnungszeit.Value = num2str(app.elapsed_time);
            app.ComputerVisionChallenge18.Visible = 'off';
            app.ComputerVisionChallenge18.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComputerVisionChallenge18 = uifigure;
            app.ComputerVisionChallenge18.Name = 'UI Figure';
            app.BlickwinkelLabel = uilabel(app.ComputerVisionChallenge18);
            app.BlickwinkelLabel.Text = 'Blickwinkel:';
            app.AngabeBlickwinkel = uieditfield(app.ComputerVisionChallenge18, 'numeric');
            app.AngabeBlickwinkel.Limits = [0 1];
            app.AngabeBlickwinkel.ValueDisplayFormat = '%.2f';
            app.AngabeBlickwinkel.ValueChangedFcn = createCallbackFcn(app, @AngabeBlickwinkelValueChanged, true);
            app.AngabeBlickwinkel.Value = 0.5;
            app.Ueberschrift = uilabel(app.ComputerVisionChallenge18);
            app.Ueberschrift.Text = 'Computer Vision Challenge 2018';
            app.Gruppe11Label = uilabel(app.ComputerVisionChallenge18);
            app.Gruppe11Label.Text = 'Gruppe 11: Julia Stroebel, Oliver Doege, Andre Thommessen, Tim Janßen, Sebastian Huegler';
            app.Beschreibung = uilabel(app.ComputerVisionChallenge18);
            app.Beschreibung.Text = {'Dieses Programm berechnet aus zwei Ansichten eine dritte Ansicht, die sich zwischen den beiden Ansichten befindet. Dazu geben Sie bitte die linke und rechte Ansicht einer Szene '; 'und den Blickwinkel des zu berechnenden Bildes an. Dieser wird prozentual angegeben, wobei 0 der linken Ansicht entspricht und 1 der rechten Ansicht.'; 'Durch Klick auf den Button "3. Ansicht berechnen" wird das Programm gestartet und die dritte Ansicht berechnet, die dann hier ausgegeben wird.'};
            app.Button3Ansichtberechnen = uibutton(app.ComputerVisionChallenge18, 'push');
            app.Button3Ansichtberechnen.ButtonPushedFcn = createCallbackFcn(app, @Button3AnsichtberechnenPushed, true);
            app.Button3Ansichtberechnen.Text = '3. Ansicht berechnen';
            app.LinkeAnsichtEditFieldLabel = uilabel(app.ComputerVisionChallenge18);
            app.LinkeAnsichtEditFieldLabel.Text = 'Linke Ansicht:';
            app.AngabeLinkeAnsicht = uieditfield(app.ComputerVisionChallenge18, 'text');
            app.AngabeLinkeAnsicht.Editable = 'off';
            app.RechteAnsichtEditFieldLabel = uilabel(app.ComputerVisionChallenge18);
            app.RechteAnsichtEditFieldLabel.Text = 'Rechte Ansicht:';
            app.AngabeRechteAnsicht = uieditfield(app.ComputerVisionChallenge18, 'text');
            app.AngabeRechteAnsicht.Editable = 'off';
            app.ButtonLinkeAnsichtladen = uibutton(app.ComputerVisionChallenge18, 'push');
            app.ButtonLinkeAnsichtladen.ButtonPushedFcn = createCallbackFcn(app, @ButtonLinkeAnsichtladenPushed, true);
            app.ButtonLinkeAnsichtladen.Text = 'Linke Ansicht laden';
            app.ButtonRechteAnsichtLaden = uibutton(app.ComputerVisionChallenge18, 'push');
            app.ButtonRechteAnsichtLaden.ButtonPushedFcn = createCallbackFcn(app, @ButtonRechteAnsichtLadenPushed, true);
            app.ButtonRechteAnsichtLaden.Text = 'Rechte Ansicht laden';
            app.BerechnungszeitEditFieldLabel = uilabel(app.ComputerVisionChallenge18);
            app.BerechnungszeitEditFieldLabel.Text = 'Berechnungszeit:';
            app.AngabeBerechnungszeit = uieditfield(app.ComputerVisionChallenge18, 'text');
            app.AngabeBerechnungszeit.Editable = 'off';
            app.Label = uilabel(app.ComputerVisionChallenge18);
            app.Label.Text = '[0-1]';
            app.minLabel = uilabel(app.ComputerVisionChallenge18);
            app.minLabel.Text = 'min';
            app.UIAxesLinkeAnsicht = uiaxes(app.ComputerVisionChallenge18);
            app.UIAxesBerechneteAnsicht = uiaxes(app.ComputerVisionChallenge18);
            app.UIAxesRechteAnsicht = uiaxes(app.ComputerVisionChallenge18);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComputerVisionChallenge18)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComputerVisionChallenge18)
        end
    end
end